classdef corr_demo < matlab.apps.AppBase

	% Properties that correspond to app components
	properties (Access = public)
		CorrelationdemobasedonPoelmavanderBaanUIFigure  matlab.ui.Figure
		GridLayout                matlab.ui.container.GridLayout
		LeftPanel                 matlab.ui.container.Panel
		Correlationdisplay        matlab.ui.control.DropDown
		CorrelationdisplayDropDownLabel  matlab.ui.control.Label
		multipass                 matlab.ui.control.CheckBox
		addgradient               matlab.ui.control.CheckBox
		addreflection             matlab.ui.control.CheckBox
		outofplane                matlab.ui.control.Slider
		OutofplanelossLabel       matlab.ui.control.Label
		noisecount                matlab.ui.control.Slider
		AmountnoiseLabel          matlab.ui.control.Label
		RefreshrateDropDown       matlab.ui.control.DropDown
		RefreshrateDropDownLabel  matlab.ui.control.Label
		intarea_size              matlab.ui.control.Slider
		part_sizeLabel_2          matlab.ui.control.Label
		part_size                 matlab.ui.control.Slider
		part_sizeLabel            matlab.ui.control.Label
		Pause                     matlab.ui.control.Button
		no_parts                  matlab.ui.control.Slider
		no_partLabel              matlab.ui.control.Label
		vert_displace             matlab.ui.control.Slider
		displ_yLabel              matlab.ui.control.Label
		hor_displace              matlab.ui.control.Slider
		displ_xLabel              matlab.ui.control.Label
		RightPanel                matlab.ui.container.Panel
		GridLayout2               matlab.ui.container.GridLayout
		GridLayout3               matlab.ui.container.GridLayout
		rms_error                 matlab.ui.control.Label
		bias_error                matlab.ui.control.Label
		axes4                     matlab.ui.control.UIAxes
		axes5                     matlab.ui.control.UIAxes
		axes3                     matlab.ui.control.UIAxes
		axes2                     matlab.ui.control.UIAxes
		axes1                     matlab.ui.control.UIAxes
	end

	% Properties that correspond to apps with auto-reflow
	properties (Access = private)
		onePanelWidth = 576;
	end

	properties (Access = private)
		refresh_timer % Timer to refresh display
		cntr=0; % iteration of the display
		imsize=100;
		I
		I1
		I2
		xp
		x0
		y0
		yp
		Ip
		iar
		ia1
		ia2
		Itemp
		Itemp2
		Irefl
		xc
		ac
		mac
		dist
		xr
		yr
		maxint
		img1handle
		img2handle
		img3handle
		plot1handle
		plot2handle
		crosshair_roi = images.roi.Crosshair;
		linex_roi = images.roi.Polyline;
		liney_roi = images.roi.Polyline;
		peak_x_history=nan(100,1);
		peak_y_history=nan(100,1);
		reset_graph=0;
		first_draw=1;
	end

	methods (Access = private)
		function redraw_stuff(app)
			part_amount=round(app.no_parts.Value);
			psize=round(app.part_size.Value);
			iasize=round(app.intarea_size.Value/2)*2;
			vx=app.hor_displace.Value;
			vy=app.vert_displace.Value;
			if app.cntr==0 %only during initialization
				app.x0 = rand(part_amount,1).*app.imsize;
				app.y0 = rand(part_amount,1).*app.imsize;
				app.I=zeros(app.imsize,app.imsize);
				app.I1=app.I;
				app.I2=app.I;
				app.maxint = 256;
				app.xr = 1:app.imsize;
				app.yr = 1:app.imsize;
				[app.xr,app.yr] = meshgrid(app.xr,app.yr);

				app.Irefl = app.I;
				for k = 1:(app.imsize-1)
					app.Irefl(round(71-k./5),k) = 128;
					app.Irefl(round(72-k./5),k) = 128;
				end

				set(app.axes1,'DataAspectRatio',[1 1 1])
				set(app.axes2,'DataAspectRatio',[1 1 1])

				app.axes1.Colormap  = gray;
				app.axes2.Colormap  = gray;
				app.axes3.Colormap  = parula;

				app.img1handle=imagesc(zeros(app.imsize,app.imsize),'Parent',app.axes1);
				app.img2handle=imagesc(zeros(app.imsize,app.imsize),'Parent',app.axes2);
				if strcmp(app.Correlationdisplay.Value, '2D')
					reset(app.axes3)
					app.img3handle=imagesc(zeros(app.imsize,iasize),'Parent',app.axes3);
					set(app.axes3,'DataAspectRatio',[1 1 1])
					set(app.axes3,'Clipping','On')
					app.axes3.XLabel.String = '';
					app.axes3.YLabel.String = '';
					app.axes3.ZLabel.String = '';
				else
					app.img3handle=surf(zeros(app.imsize,iasize),'Parent',app.axes3);
					set(app.axes3,'DataAspectRatio',[1 1 0.04])
					set(app.axes3,'Clipping','Off')
					app.axes3.ZLim=[0 1];
					app.axes3.XLabel.String = 'x';
					app.axes3.YLabel.String = 'y';
					app.axes3.ZLabel.String = 'correlation';
				end
				app.plot1handle=plot(app.peak_x_history,'Parent',app.axes4);
				app.plot2handle=plot(app.peak_y_history,'Parent',app.axes5);
				app.axes1.XLim=[1 app.imsize];
				app.axes1.YLim=[1 app.imsize];
				app.axes2.XLim=[1 app.imsize];
				app.axes2.YLim=[1 app.imsize];

				app.axes3.XLim=[1 iasize*1];
				app.axes3.YLim=[1 iasize*1];

				app.axes3.ZLim=[0 1];

				app.crosshair_roi = images.roi.Crosshair(app.axes3);
				app.crosshair_roi.Parent = app.axes3;
				app.crosshair_roi.InteractionsAllowed = 'none';
				app.crosshair_roi.LineWidth=1;
				app.crosshair_roi.Color=[0.5 0.5 0.5];

				app.linex_roi = images.roi.Polyline(app.axes4);
				app.linex_roi.Parent = app.axes4;
				app.linex_roi.InteractionsAllowed = 'none';
				app.linex_roi.LineWidth=2;
				app.linex_roi.Color=[0 1 1];

				app.liney_roi = images.roi.Polyline(app.axes5);
				app.liney_roi.Parent = app.axes5;
				app.liney_roi.InteractionsAllowed = 'none';
				app.liney_roi.LineWidth=2;
				app.liney_roi.Color=[0 1 1];
			end

			try

				app.cntr=app.cntr+1;
				app.I = zeros(app.imsize,app.imsize);

				app.I1 = app.I2;


				if size(app.x0,1) < part_amount
					app.x0 = rand(part_amount,1).*app.imsize;
					app.y0 = rand(part_amount,1).*app.imsize;
				end

				for i = 1:part_amount
					app.xp = app.x0(i);
					app.yp = app.y0(i);
					app.dist = (app.xr-app.xp).^2 + (app.yr-app.yp).^2;
					app.Ip = 255.*exp(-app.dist/psize);
					app.I = app.I + app.Ip;
				end

				app.I(app.I>app.maxint) = app.maxint;

				if app.addgradient.Value==0
					app.x0 = app.x0 + vx;
					app.y0 = app.y0 + vy;
				else
					app.x0 = app.x0 + vx;
					app.x0 = app.x0 + vx.*(app.y0-app.imsize/2)./20;
					app.y0 = app.y0 + vy;
					app.y0 = app.y0 + vy.*(app.x0-app.imsize/2)./20;
				end

				nv = app.x0>app.imsize;

				for k = find(nv==1)
					app.x0(k) = app.x0(k)-app.imsize;
				end
				nv = app.x0<0;
				for k = find(nv==1)
					app.x0(k) = app.x0(k)+app.imsize;
				end

				nv = app.y0>app.imsize;

				for k = find(nv==1)
					app.y0(k) = app.y0(k)-app.imsize;
				end
				nv = app.y0<0;
				for k = find(nv==1)
					app.y0(k) = app.y0(k)+app.imsize;
				end
				perc_loss = rand(part_amount,1);
				nv = perc_loss < app.outofplane.Value;
				xnew = rand(part_amount,1).*app.imsize;
				ynew = rand(part_amount,1).*app.imsize;
				app.x0(nv) = xnew(nv);
				app.y0(nv) = ynew(nv);
				app.I2 = app.I;
				app.iar = -(iasize/2-1):(iasize/2);
				if app.addreflection.Value==1
					app.I1 = app.I1 + app.Irefl;
					app.I2 = app.I2 + app.Irefl;
				end
				noise_count = app.noisecount.Value*255;
				app.I1 = app.I1 + rand(app.imsize,app.imsize).*noise_count; % add some noise to the images
				app.I2 = app.I2 + rand(app.imsize,app.imsize).*noise_count;
				if app.multipass.Value==0
					app.ia1 = app.I1(app.imsize/2+app.iar,app.imsize/2+app.iar); % cut out first IA without shift
				else
					app.ia1 = app.I1(app.imsize/2+app.iar-round(vy),app.imsize/2+app.iar-round(vx)); % cut out first IA without shift
				end

				app.ia2 = app.I2(app.imsize/2+app.iar,app.imsize/2+app.iar); % cut out second IA

				app.Itemp = app.I1; % visualize int. area by adding a constant value
				app.Itemp(app.imsize/2+app.iar,app.imsize/2+app.iar) = app.Itemp(app.imsize/2+app.iar,app.imsize/2+app.iar) + 128;

				app.Itemp2 = app.I2; % visualize int. area
				app.Itemp2(app.imsize/2+app.iar,app.imsize/2+app.iar) = app.Itemp2(app.imsize/2+app.iar,app.imsize/2+app.iar) + 128;


				app.ia1 = app.ia1-mean2(app.ia1);
				app.ia2 = app.ia2-mean2(app.ia2);

				%app.xc = fftshift(ifft2(fft2(app.ia2,iasize*2,iasize*2).*conj(fft2(app.ia1,iasize*2,iasize*2)))); % with zero padding
				%app.ac = fftshift(ifft2(fft2(app.ia1,iasize*2,iasize*2).*conj(fft2(app.ia1,iasize*2,iasize*2))));

				%% Create windowing function W and loss-of-correlation function F
				% Taken from PIVsuite, Adrian & Whesterweel, Particle Image Velocimetry, Cambridge University Press, 2011, Table 8.1, p. 390
				ccWindow = 'gauss'; %fastest and best performance

				iaSizeX=iasize;
				iaSizeY=iasize;

				auxX = ones(iaSizeY,1)*(-(iaSizeX-1)/2:(iaSizeX-1)/2);
				auxY = (-(iaSizeY-1)/2:(iaSizeY-1)/2)'*ones(1,iaSizeX);
				EtaX = auxX/iaSizeX;
				EtaY = auxY/iaSizeY;
				KsiX = 2*EtaX;
				KsiY = 2*EtaY;

				if strcmpi(ccWindow,'uniform')
					W = ones(iaSizeY,iaSizeX);
					F = (1-abs(KsiX)).*(1-abs(KsiY));
				elseif strcmpi(ccWindow,'parzen')
					% window function W
					W = (1-2*abs(EtaX)).*(1-2*abs(EtaY));
					% loss of correlation function F
					auxFx = auxX + NaN; % initialization
					auxFy = auxX + NaN;
					auxOK = logical(abs(KsiX)<=1/2);
					auxFx(auxOK) = 1-6*KsiX(auxOK).^2+6*abs(KsiX(auxOK)).^3;
					auxFx(~auxOK) = 2-6*abs(KsiX(~auxOK))+6*KsiX(~auxOK).^2-2*abs(KsiX(~auxOK)).^3;
					auxOK = logical(abs(KsiY)<=1/2);
					auxFy(auxOK) = 1-6*KsiY(auxOK).^2+6*abs(KsiY(auxOK)).^3;
					auxFy(~auxOK) = 2-6*abs(KsiY(~auxOK))+6*KsiY(~auxOK).^2-2*abs(KsiY(~auxOK)).^3;
					F = auxFx.*auxFy;
				elseif strcmpi(ccWindow,'Hanning')
					W = (1/2+1/2*cos(2*pi*EtaX)).*(1/2+1/2*cos(2*pi*EtaY));
					F = (2/3*(1-abs(KsiX)).*(1+1/2*cos(2*pi*KsiX))+1/2/pi*sin(2*pi*abs(KsiX))) .*...
						(2/3*(1-abs(KsiY)).*(1+1/2*cos(2*pi*KsiY))+1/2/pi*sin(2*pi*abs(KsiY)));
				elseif strcmpi(ccWindow,'Welch')

					W = (1-(2*EtaX).^2).*(1-(2*EtaY).^2);
					F = (1-5*KsiX.^2+5*abs(KsiX).^3-abs(KsiX).^5) .* ...
						(1-5*KsiY.^2+5*abs(KsiY).^3-abs(KsiY).^5);

				elseif strcmpi(ccWindow,'Gauss')
					W = exp(-8*EtaX.^2).*exp(-8*EtaY.^2);
					F = exp(-4*KsiX.^2).*exp(-4*KsiY.^2);

				elseif strcmpi(ccWindow,'Gauss1')
					W = exp(-8*(EtaX.^2+EtaY.^2)) - exp(-2);
					W(W<0) = 0;
					W = W/max(max(W));
					F = NaN;
				elseif strcmpi(ccWindow,'Gauss2')
					W = exp(-16*(EtaX.^2+EtaY.^2)) - exp(-4);
					W(W<0) = 0;
					W = W/max(max(W));
					F = NaN;
				elseif strcmpi(ccWindow,'Gauss0.5')
					W = exp(-4*(EtaX.^2+EtaY.^2)) - exp(-1);
					W(W<0) = 0;
					W = W/max(max(W));
					F = NaN;
				elseif strcmpi(ccWindow,'Nogueira')
					W = 9*(1-4*abs(EtaX)+4*EtaX.^2).*(1-4*abs(EtaY)+4*EtaY.^2);
					F = NaN;
				elseif strcmpi(ccWindow,'Hanning2')
					W = (1/2+1/2*cos(2*pi*EtaX)).^2.*(1/2+1/2*cos(2*pi*EtaY)).^2;
					F = NaN;
				elseif strcmpi(ccWindow,'Hanning4')
					W = (1/2+1/2*cos(2*pi*EtaX)).^4.*(1/2+1/2*cos(2*pi*EtaY)).^4;
					F = NaN;
				end
				% Limit F to not be too small
				F(F<0.5) = 0.5;

				app.ia1=app.ia1.*W;
				app.ia2=app.ia2.*W;

				app.xc = real(ifft2(conj(fft2(app.ia1)).*fft2(app.ia2)));
				app.xc = fftshift(fftshift(app.xc, 1), 2);
				app.ac = real(ifft2(conj(fft2(app.ia1)).*fft2(app.ia1)));
				app.ac = fftshift(fftshift(app.ac, 1), 2);
				if  ~isnan(F)
					app.ac=app.ac./F;
				end

				app.mac = max(app.ac(:)); % get height of highest peak
				correlation_matrix = app.xc./app.mac;
				%{
				%cropping the correlation matrix so it has same size as int area again
				idx1=size(correlation_matrix,1)/4*1 ;
				idx2=size(correlation_matrix,1)/4*3;
				correlation_matrix=correlation_matrix(idx1:idx2,idx1:idx2);
				%}
				corval = max(app.xc(:))./app.mac;
				[peaky,peakx] = find(correlation_matrix==max(correlation_matrix(:)));
				%%{
				if isempty(peakx)
					peakx=2;
				end
				if isempty(peaky)
					peaky=2;
				end
				peaky=peaky(1);
				peakx=peakx(1);
				if peakx==1
					peakx=2;
				end
				if peaky==1
					peaky=2;
				end
				if peakx==size(correlation_matrix,1)
					peakx=2;
				end
				if peaky==size(correlation_matrix,1)
					peaky=2;
				end

				%%}
				%the following 8 lines are copyright (c) 1998, Uri Shavit, Roi Gurka, Alex Liberzon, Technion – Israel Institute of Technology
				%http://urapiv.wordpress.com
				f0 = log(correlation_matrix(peaky,peakx));
				f1 = log(correlation_matrix(peaky-1,peakx));
				f2 = log(correlation_matrix(peaky+1,peakx));
				subpeaky = peaky+ (f1-f2)/(2*f1-4*f0+2*f2);
				f0 = log(correlation_matrix(peaky,peakx));
				f1 = log(correlation_matrix(peaky,peakx-1));
				f2 = log(correlation_matrix(peaky,peakx+1));
				subpeakx = peakx+ (f1-f2)/(2*f1-4*f0+2*f2);

				if ~isreal(subpeakx)
					subpeakx = real(subpeakx);
				end
				if ~isreal(subpeaky)
					subpeaky = real(subpeaky);
				end

				if isnan(subpeakx)
					subpeakx = 1;
				end
				if isnan(subpeaky)
					subpeaky = 1;
				end
				if app.multipass.Value==1
					subpeakx=subpeakx+round(vx);
					subpeaky=subpeaky+round(vy);
				end

				if ~isempty(subpeakx)
					%%xaxis
					app.peak_x_history(end+1,1)=subpeakx-size(correlation_matrix,2)/2-1;
					if numel(app.peak_x_history)>0
						app.peak_x_history(1,:)=[];
					end

					if ~isempty(app.peak_x_history)
						app.plot1handle.XData=1 :numel(app.peak_x_history);
					end
					if ~isempty(app.peak_y_history)
						app.plot1handle.YData=app.peak_x_history;
					end
					app.axes4.XLim=[1 100];
					if ~isempty(app.peak_x_history)
						app.axes4.YLim=[mean(app.peak_x_history(:),'omitnan')-4*std(app.peak_x_history(:),'omitnan')-0.1 mean(app.peak_x_history(:),'omitnan')+4*std(app.peak_x_history(:),'omitnan')+0.1];
					end
					app.linex_roi.Position=[1 vx; 100 vx];

					%% y axis
					app.peak_y_history(end+1,1)=subpeaky-size(correlation_matrix,2)/2-1;
					if numel(app.peak_y_history)>0
						app.peak_y_history(1,:)=[];
					end
					if ~isempty(app.peak_x_history)
						app.plot2handle.XData=1 :numel(app.peak_y_history);
					end
					if ~isempty(app.peak_y_history)
						app.plot2handle.YData=app.peak_y_history;
					end
					app.axes5.XLim=[1 100];
					if ~isempty(app.peak_y_history)
						app.axes5.YLim=[mean(app.peak_y_history(:),'omitnan')-4*std(app.peak_y_history(:),'omitnan')-0.1 mean(app.peak_y_history(:),'omitnan')+4*std(app.peak_y_history(:),'omitnan')+0.1];
					end
					app.liney_roi.Position=[1 vy; 100 vy];
				end
				if ~isnan(corval)
					app.axes3.Title.String= ['max cor.: ' sprintf('%0.2f',corval) '; peak x: '  sprintf('%0.2f',subpeakx-size(correlation_matrix,2)/2-1.5) '; peak y: '  sprintf('%0.2f',subpeaky-size(correlation_matrix,2)/2-1.5)];
				end
				app.img1handle.CData = app.Itemp;
				app.img2handle.CData = app.Itemp2;
				if ~isempty(correlation_matrix)
					if strcmp(app.Correlationdisplay.Value,'2D')
						app.img3handle.CData = correlation_matrix;
					else
						app.img3handle.ZData = correlation_matrix;
						set(app.axes3,'DataAspectRatio',[1 1 1/iasize])
					end
				end
				if ~isempty(peakx)
					if app.multipass.Value==1
						app.crosshair_roi.Position = [subpeakx-round(vx),subpeaky-round(vy)];
					else
						app.crosshair_roi.Position = [subpeakx,subpeaky];
					end
				end
				if app.reset_graph == 1
					app.peak_x_history(end)=nan;
					app.peak_y_history(end)=nan;
					app.reset_graph = 0;
				end
				if app.first_draw == 1
					app.first_draw=0;
					app.peak_x_history(:)=nan;
					app.peak_y_history(:)=nan;
				end
				bias_error_x = mean(app.peak_x_history - vx,'omitnan');
				rms_error_x = std(app.peak_x_history,'omitnan');
				bias_error_y = mean(app.peak_y_history - vy,'omitnan');
				rms_error_y = std(app.peak_y_history,'omitnan');
				bias_error_tot = (bias_error_x.^2+bias_error_y.^2).^0.5;
				rms_error_tot = (rms_error_x.^2+rms_error_y.^2).^0.5;
				bias_error_tot_perc=bias_error_tot/((vx.^2+vy.^2).^0.5)*100;
				rms_error_tot_perc=rms_error_tot/((vx.^2+vy.^2).^0.5)*100;
				app.bias_error.Text=['Bias Error: ' sprintf('%0.2f',bias_error_tot) ' px' newline '(' sprintf('%0.2f',bias_error_tot_perc) ' %)'];
				app.rms_error.Text=['RMS Error: ' sprintf('%0.2f',rms_error_tot) ' px' newline '(' sprintf('%0.2f',rms_error_tot_perc) ' %)'];

				%% gauss peakfitting graphics
				%{
peakdata_x= correlation_matrix(peaky, peakx-1:peakx+1);	
x_data=peakx-1:peakx+1;
[sigma, mu] = gaussfit(x_data, peakdata_x);
x_data_p=peakx-2:0.05:peakx+2;
y_data_p = 1/(sqrt(2*pi)* sigma ) * exp( - (x_data_p-mu).^2 / (2*sigma^2));
%bar(x_data,peakdata_x);hold on;plot(x_data_p,y_data_p);hold off;


peakdata_y= correlation_matrix(peaky-1:peaky+1,peakx)';
x_data=peaky-1:peaky+1;
[sigma, mu] = gaussfit(x_data, peakdata_y);

x_data_p=peaky-2:0.05:peaky+2;
y_data_p = 1/(sqrt(2*pi)* sigma ) * exp( - (x_data_p-mu).^2 / (2*sigma^2));
				%}


			catch ME
				disp('error in lines')
				ME.stack.line
				ME.message
			end
			drawnow limitrate

		end


		function [sigma, mu] = gaussfit(x, y, sigma0, mu0)
			% [sigma, mu] = gaussfit( x, y, sigma0, mu0 )
			% Fits a guassian probability density function into (x,y) points using iterative
			% LMS method. Gaussian p.d.f is given by:
			% y = 1/(sqrt(2*pi)*sigma)*exp( -(x - mu)^2 / (2*sigma^2))
			% The results are much better than minimazing logarithmic residuals
			%
			% INPUT:
			% sigma0 - initial value of sigma (optional)
			% mu0 - initial value of mean (optional)
			%
			% OUTPUT:
			% sigma - optimal value of standard deviation
			% mu - optimal value of mean
			%
			% REMARKS:
			% The function does not always converge in which case try to use initial
			% values sigma0, mu0. Check also if the data is properly scaled, i.e. p.d.f
			% should approx. sum up to 1
			%
			% VERSION: 23.02.2012
			%
			% EXAMPLE USAGE:
			% x = -10:1:10;
			% s = 2;
			% m = 3;
			% y = 1/(sqrt(2*pi)* s ) * exp( - (x-m).^2 / (2*s^2)) + 0.02*randn( 1, 21 );
			% [sigma,mu] = gaussfit( x, y )
			% xp = -10:0.1:10;
			% yp = 1/(sqrt(2*pi)* sigma ) * exp( - (xp-mu).^2 / (2*sigma^2));
			% plot( x, y, 'o', xp, yp, '-' );
			% Maximum number of iterations
			Nmax = 10;
			if( length( x ) ~= length( y ))
				fprintf( 'x and y should be of equal length\n\r' );
				exit;
			end
			n = length( x );
			x = reshape( x, n, 1 );
			y = reshape( y, n, 1 );
			%sort according to x
			X = [x,y];
			X = sortrows( X );
			x = X(:,1);
			y = X(:,2);
			%Checking if the data is normalized
			dx = diff( x );
			dy = 0.5*(y(1:length(y)-1) + y(2:length(y)));
			s = sum( dx .* dy );
			if( s > 1.5 | s < 0.5 )
				fprintf( 'Data is not normalized! The pdf sums to: %f. Normalizing...\n\r', s );
				y = y ./ s;
			end
			X = zeros( n, 3 );
			X(:,1) = 1;
			X(:,2) = x;
			X(:,3) = (x.*x);
			% try to estimate mean mu from the location of the maximum
			[ymax,index]=max(y);
			mu = x(index);
			% estimate sigma
			sigma = 1/(sqrt(2*pi)*ymax);
			if( nargin == 3 )
				sigma = sigma0;
			end
			if( nargin == 4 )
				mu = mu0;
			end
			%xp = linspace( min(x), max(x) );
			% iterations
			for i=1:Nmax
				%    yp = 1/(sqrt(2*pi)*sigma) * exp( -(xp - mu).^2 / (2*sigma^2));
				%    plot( x, y, 'o', xp, yp, '-' );
				dfdsigma = -1/(sqrt(2*pi)*sigma^2)*exp(-((x-mu).^2) / (2*sigma^2));
				dfdsigma = dfdsigma + 1/(sqrt(2*pi)*sigma).*exp(-((x-mu).^2) / (2*sigma^2)).*((x-mu).^2/sigma^3);
				dfdmu = 1/(sqrt(2*pi)*sigma)*exp(-((x-mu).^2)/(2*sigma^2)).*(x-mu)/(sigma^2);
				F = [ dfdsigma dfdmu ];
				a0 = [sigma;mu];
				f0 = 1/(sqrt(2*pi)*sigma).*exp( -(x-mu).^2 /(2*sigma^2));
				a = (F'*F)^(-1)*F'*(y-f0) + a0;
				sigma = a(1);
				mu = a(2);

				if( sigma < 0 )
					sigma = abs( sigma );
					fprintf( 'Instability detected! Rerun with initial values sigma0 and mu0! \n\r' );
					fprintf( 'Check if your data is properly scaled! p.d.f should approx. sum up to \n\r' );
					exit;
				end
			end
		end
	end

	% Callbacks that handle component events
	methods (Access = private)

		% Code that executes after component creation
		function startupFcn(app)
			warning off
			delete(timerfind);
			warning on
			app.cntr=0;
			app.refresh_timer = timer('Period',1/10,'Name','MyTimer','ExecutionMode','fixedRate');
			app.refresh_timer.TimerFcn = @(~,~)app.redraw_stuff;
			drawnow
			pause(1)
			start(app.refresh_timer)
		end

		% Button pushed function: Pause
		function Pause_sim(app, event)
			if strcmp (app.refresh_timer.Running,'on')
				stop(app.refresh_timer)
				app.Pause.Text='Restart';
			elseif strcmp (app.refresh_timer.Running,'off')
				app.Pause.Text='Pause';
				app.cntr=0;
				stop(app.refresh_timer)
				pause(0.1)
				start(app.refresh_timer)
			end
		end

		% Close request function: 
		% CorrelationdemobasedonPoelmavanderBaanUIFigure
		function CorrelationdemobasedonPoelmavanderBaanUIFigureCloseRequest(app, event)
			try
				stop(app.refresh_timer)
			catch
			end
			warning off
			delete(timerfind);
			warning on
			delete(app)
		end

		% Value changed function: Correlationdisplay, RefreshrateDropDown, 
		% ...and 1 other component
		function ValueChanged(app, event)
			app.cntr=0;
			stop(app.refresh_timer)
			switch app.RefreshrateDropDown.Value
				case '5 Hz'
					refresh=1/5;
				case '10 Hz'
					refresh=1/10;
				case '20 Hz'
					refresh=1/20;
				case '40 Hz'
					refresh=1/40;
				case '100 Hz'
					refresh=1/100;
			end
			app.refresh_timer.Period = refresh ;
			clear_history(app, event)
			start(app.refresh_timer)
		end

		% Value changed function: hor_displace, vert_displace
		function clear_history(app, event)
			app.peak_x_history = nan(100,1);
			app.peak_y_history = nan(100,1);
			app.reset_graph = 1;
		end

		% Value changed function: addgradient, addreflection, multipass, 
		% ...and 4 other components
		function keep_history(app, event)
			app.reset_graph = 1;			
		end

		% Changes arrangement of the app based on UIFigure width
		function updateAppLayout(app, event)
			currentFigureWidth = app.CorrelationdemobasedonPoelmavanderBaanUIFigure.Position(3);
			if(currentFigureWidth <= app.onePanelWidth)
			    % Change to a 2x1 grid
			    app.GridLayout.RowHeight = {699, 699};
			    app.GridLayout.ColumnWidth = {'1x'};
			    app.RightPanel.Layout.Row = 2;
			    app.RightPanel.Layout.Column = 1;
			else
			    % Change to a 1x2 grid
			    app.GridLayout.RowHeight = {'1x'};
			    app.GridLayout.ColumnWidth = {299, '1x'};
			    app.RightPanel.Layout.Row = 1;
			    app.RightPanel.Layout.Column = 2;
			end
		end
	end

	% Component initialization
	methods (Access = private)

		% Create UIFigure and components
		function createComponents(app)
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			app.CorrelationdemobasedonPoelmavanderBaanUIFigure = uifigure('Visible', 'off');
			app.CorrelationdemobasedonPoelmavanderBaanUIFigure.Name = 'Correlation demo, based on Poelma & van der Baan ';
			app.CorrelationdemobasedonPoelmavanderBaanUIFigure.CloseRequestFcn = createCallbackFcn(app, @CorrelationdemobasedonPoelmavanderBaanUIFigureCloseRequest, true);
			app.CorrelationdemobasedonPoelmavanderBaanUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
			app.GridLayout = uigridlayout(app.CorrelationdemobasedonPoelmavanderBaanUIFigure);
			app.LeftPanel = uipanel(app.GridLayout);
			app.displ_xLabel = uilabel(app.LeftPanel);
			app.displ_xLabel.Text = 'x displacement';
			app.hor_displace = uislider(app.LeftPanel);
			app.hor_displace.Limits = [-20 20];
			app.hor_displace.ValueChangedFcn = createCallbackFcn(app, @clear_history, true);
			app.hor_displace.Value = 4;
			app.displ_yLabel = uilabel(app.LeftPanel);
			app.displ_yLabel.Text = 'y displacement';
			app.vert_displace = uislider(app.LeftPanel);
			app.vert_displace.Limits = [-20 20];
			app.vert_displace.ValueChangedFcn = createCallbackFcn(app, @clear_history, true);
			app.no_partLabel = uilabel(app.LeftPanel);
			app.no_partLabel.Text = {'Number of '; 'particles'};
			app.no_parts = uislider(app.LeftPanel);
			app.no_parts.Limits = [1 400];
			app.no_parts.MajorTicks = [1 100 200 300 400];
			app.no_parts.MajorTickLabels = {'1', '100', '200', '300', '400'};
			app.no_parts.ValueChangedFcn = createCallbackFcn(app, @keep_history, true);
			app.no_parts.Value = 128;
			app.Pause = uibutton(app.LeftPanel, 'push');
			app.Pause.ButtonPushedFcn = createCallbackFcn(app, @Pause_sim, true);
			app.Pause.Text = 'Pause';
			app.part_sizeLabel = uilabel(app.LeftPanel);
			app.part_sizeLabel.Text = 'Particle size';
			app.part_size = uislider(app.LeftPanel);
			app.part_size.Limits = [1 10];
			app.part_size.ValueChangedFcn = createCallbackFcn(app, @keep_history, true);
			app.part_size.Value = 1;
			app.part_sizeLabel_2 = uilabel(app.LeftPanel);
			app.part_sizeLabel_2.Text = {'Size of the '; 'interrogation area'};
			app.intarea_size = uislider(app.LeftPanel);
			app.intarea_size.Limits = [8 96];
			app.intarea_size.MajorTicks = [8 32 64 96];
			app.intarea_size.MajorTickLabels = {'8', '32', '64', '96'};
			app.intarea_size.ValueChangedFcn = createCallbackFcn(app, @ValueChanged, true);
			app.intarea_size.MinorTicks = [8 16 24 32 40 48 56 64 72 80 88 96 104 112 120 128];
			app.intarea_size.Value = 32;
			app.RefreshrateDropDownLabel = uilabel(app.LeftPanel);
			app.RefreshrateDropDownLabel.Text = 'Refresh rate';
			app.RefreshrateDropDown = uidropdown(app.LeftPanel);
			app.RefreshrateDropDown.Items = {'5 Hz', '10 Hz', '20 Hz', '40 Hz', '100 Hz'};
			app.RefreshrateDropDown.ValueChangedFcn = createCallbackFcn(app, @ValueChanged, true);
			app.RefreshrateDropDown.Value = '10 Hz';
			app.AmountnoiseLabel = uilabel(app.LeftPanel);
			app.AmountnoiseLabel.Text = 'Amount noise';
			app.noisecount = uislider(app.LeftPanel);
			app.noisecount.Limits = [0 1];
			app.noisecount.MajorTicks = [0 0.25 0.5 0.75 1];
			app.noisecount.ValueChangedFcn = createCallbackFcn(app, @keep_history, true);
			app.OutofplanelossLabel = uilabel(app.LeftPanel);
			app.OutofplanelossLabel.Text = 'Out of plane loss';
			app.outofplane = uislider(app.LeftPanel);
			app.outofplane.Limits = [0 1];
			app.outofplane.MajorTicks = [0 0.25 0.5 0.75 1];
			app.outofplane.ValueChangedFcn = createCallbackFcn(app, @keep_history, true);
			app.addreflection = uicheckbox(app.LeftPanel);
			app.addreflection.ValueChangedFcn = createCallbackFcn(app, @keep_history, true);
			app.addreflection.Text = 'Add reflection';
			app.addgradient = uicheckbox(app.LeftPanel);
			app.addgradient.ValueChangedFcn = createCallbackFcn(app, @keep_history, true);
			app.addgradient.Text = 'Add gradient';
			app.multipass = uicheckbox(app.LeftPanel);
			app.multipass.ValueChangedFcn = createCallbackFcn(app, @keep_history, true);
			app.multipass.Text = 'Simulate multipass';
			app.CorrelationdisplayDropDownLabel = uilabel(app.LeftPanel);
			app.CorrelationdisplayDropDownLabel.Text = 'Correlation display';
			app.Correlationdisplay = uidropdown(app.LeftPanel);
			app.Correlationdisplay.Items = {'2D', '3D'};
			app.Correlationdisplay.ValueChangedFcn = createCallbackFcn(app, @ValueChanged, true);
			app.Correlationdisplay.Value = '2D';
			app.RightPanel = uipanel(app.GridLayout);
			app.GridLayout2 = uigridlayout(app.RightPanel);
			app.axes1 = uiaxes(app.GridLayout2);
			title(app.axes1, 'interrogation area A')
			app.axes1.XLim = [0 1];
			app.axes1.XTick = [];
			app.axes1.XTickLabel = {};
			app.axes1.YTick = [];
			app.axes1.YTickLabel = {};
			app.axes1.ZTick = [];
			app.axes1.ZTickLabel = '';
			colormap(app.axes1, 'parula')
			app.axes2 = uiaxes(app.GridLayout2);
			title(app.axes2, 'interrogation area B')
			app.axes2.XTick = [];
			app.axes2.XTickLabel = {};
			app.axes2.YTick = [];
			app.axes2.YTickLabel = {};
			app.axes2.ZTick = [];
			app.axes2.ZTickLabel = '';
			colormap(app.axes2, 'parula')
			app.axes3 = uiaxes(app.GridLayout2);
			title(app.axes3, 'Correlation matrix')
			app.axes3.XTick = [];
			app.axes3.XTickLabel = {};
			app.axes3.YTick = [];
			app.axes3.YTickLabel = {};
			app.axes3.ZTick = [];
			app.axes3.ZTickLabel = '';
			colormap(app.axes3, 'parula')
			app.axes5 = uiaxes(app.GridLayout2);
			title(app.axes5, 'Measured y displacement')
			xlabel(app.axes5, 'time')
			ylabel(app.axes5, 'y position in px')
			app.axes5.XLim = [0 1];
			app.axes5.XTick = [];
			app.axes5.XTickLabel = '';
			app.axes5.ZTick = [];
			app.axes5.ZTickLabel = '';
			colormap(app.axes5, 'parula')
			app.axes4 = uiaxes(app.GridLayout2);
			title(app.axes4, 'Measured x displacement')
			xlabel(app.axes4, 'time')
			ylabel(app.axes4, 'x position in px')
			app.axes4.XLim = [0 1];
			app.axes4.XTick = [];
			app.axes4.XTickLabel = '';
			app.axes4.ZTick = [];
			app.axes4.ZTickLabel = '';
			colormap(app.axes4, 'parula')
			app.GridLayout3 = uigridlayout(app.GridLayout2);
			app.bias_error = uilabel(app.GridLayout3);
			app.bias_error.Text = {'Bias Error: 0.00 px'; '(XX %)'};
			app.rms_error = uilabel(app.GridLayout3);
			app.rms_error.Text = {'RMS Error: 0.00 px'; '(XX %)'};
			% Show the figure after all components are created
			app.CorrelationdemobasedonPoelmavanderBaanUIFigure.Visible = 'on';
		end
	end

	% App creation and deletion
	methods (Access = public)

		% Construct app
		function app = corr_demo

			runningApp = getRunningApp(app);

			% Check for running singleton app
			if isempty(runningApp)

				% Create UIFigure and components
				createComponents(app)

				% Register the app with App Designer
				registerApp(app, app.CorrelationdemobasedonPoelmavanderBaanUIFigure)

				% Execute the startup function
				runStartupFcn(app, @startupFcn)
			else

				% Focus the running singleton app
				figure(runningApp.CorrelationdemobasedonPoelmavanderBaanUIFigure)

				app = runningApp;
			end

			if nargout == 0
				clear app
			end
		end

		% Code that executes before app deletion
		function delete(app)

			% Delete UIFigure when app is deleted
			delete(app.CorrelationdemobasedonPoelmavanderBaanUIFigure)
		end
	end
end