classdef PIVlab_Delay_Generator < matlab.apps.AppBase

	% Properties that correspond to app components
	properties (Access = public)
		OLT_Delay_Generator_Fig   matlab.ui.Figure
		TextArea                  matlab.ui.control.TextArea
		RunningLabel              matlab.ui.control.Label
		runninglamp               matlab.ui.control.Lamp
		Switch                    matlab.ui.control.Switch
		EdgereactDropDown         matlab.ui.control.DropDown
		EdgereactDropDownLabel    matlab.ui.control.Label
		Signal_Period_EditField   matlab.ui.control.NumericEditField
		Signal_waitLabel_2        matlab.ui.control.Label
		Signal_wait_EditField     matlab.ui.control.NumericEditField
		Signal_waitLabel          matlab.ui.control.Label
		DisconnectButton          matlab.ui.control.Button
		ConnectButton             matlab.ui.control.Button
		connect_lamp              matlab.ui.control.Lamp
		RefreshButton             matlab.ui.control.Button
		PortDropDown              matlab.ui.control.DropDown
		PortDropDownLabel         matlab.ui.control.Label
		DevicenameEditField       matlab.ui.control.EditField
		DevicenameEditFieldLabel  matlab.ui.control.Label
	end


	properties (Access = private)
		serpo = []; % serialport object
		signal_period=10;
		signal_wait=10;
		edge_react=0;

	end

	methods (Access = private)
		function connect_serial(app)
			app.connect_lamp.Color='Yellow';
			pause(0.25)
			delete(app.serpo)
			try
				s = serialport(app.PortDropDown.Value,9600,'Timeout',0.5);
				configureTerminator(s,'CR/LF');
				app.serpo=s;
				app.connect_lamp.Color='Green';
				app.TextArea.Value=["Connected to " app.PortDropDown.Value];
				pause(0.01);
				app.TextArea.scroll('bottom');
			catch ME
				app.connect_lamp.Color='Red';
				app.serpo=[];
				app.TextArea.Value=ME.message;
			end
		end


		function serial_answer = control_simple_sync_serial(app,switch_it)
			lastwarn ('');
			device_name=app.DevicenameEditField.Value;
			EXTSKP=num2str(app.edge_react);
			EXTDLY=num2str(app.signal_wait*10);
			INTERF=num2str(app.signal_period*10);
			if switch_it==1
				flush(app.serpo)
				pause(0.1)
				send_string=['TALKINGTO:' device_name ';FREQ:0;CAM:0;ENER:0;ener%:0;F1EXP:0;INTERF:' INTERF ';EXTDLY:' EXTDLY ';EXTSKP:' EXTSKP ';LASER:enable'];
				writeline(app.serpo,send_string);
			else
				flush(app.serpo)
				pause(0.1)
				send_string=['TALKINGTO:' device_name ';FREQ:0;CAM:0;ENER:0;ener%:0;F1EXP:0;INTERF:100;EXTDLY:100;EXTSKP:0;LASER:disable'];
				writeline(app.serpo,send_string);
			end
			warning off
			pause(0.1)
			serial_answer=readline(app.serpo);
			warning on
			sync_setting=serial_answer;
			if isempty(sync_setting)
				sync_setting="No answer";
			end

			app.TextArea.Value=[app.TextArea.Value;  sync_setting];
			pause(0.1);
			app.TextArea.scroll('bottom');

			app.runninglamp.Color='yellow'; %yellow = warning


			C = strsplit(sync_setting,'\t');

			if ~isempty(C)
				if size(C,2)==8 %entspricht standard datenpaket
					if strcmp(C{8},'1') %laser is reported to be on
						%laser on
						app.runninglamp.Color='green'; %green = on
					else
						app.runninglamp.Color='red'; %red = off
					end
				end
				if size(C,2)==12 %entspricht erweitertem datenpaket
					if strcmp(C{8},'1') %laser is reported to be on
						app.runninglamp.Color='green'; %green = on
					else
						app.runninglamp.Color='red'; %red = off
					end

				end
			end
			%pause(0.5)
			[~, msgid] = lastwarn;
			if strcmp(msgid,'serialport:serialport:ReadlineWarning')
				%disp('didnt answer')
				app.Switch.Value = 'Off';
				app.runninglamp.Color='red'; %red = off
			end
			%append to text box and
			%check laser running bit, set lamp
			%alle slider und textboxes sollen callback haben mit value
			%changed. wenn geändert: check den status vom switch, und
			%schreibe string entweder mit laser an oder mit laser aus.
			%schreibe in textdatei den log
			%"5	305	2000	1"
		end
	end


	% Callbacks that handle component events
	methods (Access = private)

		% Code that executes after component creation
		function startupFcn(app)
			%set (app.OLT_Delay_Generator_Fig,'Visible','off');
			%currentsize=get(app.OLT_Delay_Generator_Fig,'Position');
			%position=get(0,'Screensize');
			%set(app.OLT_Delay_Generator_Fig,'Position',[position(3)-currentsize(3) position(4)/2 - currentsize(4)/2 currentsize(3) currentsize(4)])
			%set (app.OLT_Delay_Generator_Fig,'Visible','on');
			serports=serialportlist("available");
			app.PortDropDown.Items=serports;
			app.TextArea.Value='';
			EdgereactDropDownValueChanged(app)
			Signal_Period_EditFieldValueChanged(app)
			Signal_wait_EditFieldValueChanged2(app)
			set(app.OLT_Delay_Generator_Fig,'WindowStyle','alwaysontop')


		end

		% Button pushed function: ConnectButton
		function ConnectButtonPushed(app, event)
			connect_serial(app);
		end

		% Close request function: OLT_Delay_Generator_Fig
		function OLT_Delay_Generator_FigCloseRequest(app, event)

			delete(app.serpo)
			delete(app)
		end

		% Value changed function: Switch
		function SwitchValueChanged(app, event)
			value = app.Switch.Value;
			app.runninglamp.Color='yellow';
			if ~isempty(app.serpo)
				if strcmp(value,'On')
					control_simple_sync_serial(app,1);
				else
					control_simple_sync_serial(app,0);
				end
			else
				app.Switch.Value='Off';
			end
		end

		% Key press function: OLT_Delay_Generator_Fig
		function OLT_Delay_Generator_FigKeyPress(app, event)
			key = event.Key;
			if size(event.Modifier,2) ==2
				if isequal(event.Modifier{1},'shift') && isequal(event.Modifier{2},'control')
					switch key
						case 'l'
							if strcmp(app.Switch.Value,'Off')
								app.Switch.Value = 'On';
							else
								app.Switch.Value = 'Off';
							end
							SwitchValueChanged(app);
						case 'multiply' %+
							if app.EnergySlider.Value < 100
								app.EnergySlider.Value = round(app.EnergySlider.Value) +1;
								EnergySliderValueChanged(app);
								%SwitchValueChanged(app);
							end
							disp('+')
						case 'hyphen' %-
							disp('-')
							if app.EnergySlider.Value > 0
								app.EnergySlider.Value = round(app.EnergySlider.Value) -1;
								EnergySliderValueChanged(app);
								%SwitchValueChanged(app);
							end
					end
				end
			end
		end

		% Button pushed function: RefreshButton
		function RefreshButtonPushed(app, event)
			warning off all
			snew = instrfind;
			clear snew
			app.connect_lamp.Color='Red';
			app.serpo=[];
			warning on all
			serports=serialportlist("all");
			app.PortDropDown.Items=serports;
		end

		% Value changed function: EdgereactDropDown
		function EdgereactDropDownValueChanged(app, event)
			value = app.EdgereactDropDown.Value;
			if strcmp(value,'Rising')
				app.edge_react=0;
			elseif strcmp(value,'Falling')
				app.edge_react=1;
			end
			SwitchValueChanged(app);
		end

		% Value changed function: Signal_wait_EditField
		function Signal_wait_EditFieldValueChanged2(app, event)
			value = app.Signal_wait_EditField.Value;
			app.signal_wait = value;
			SwitchValueChanged(app);
		end

		% Value changed function: Signal_Period_EditField
		function Signal_Period_EditFieldValueChanged(app, event)
			value = app.Signal_Period_EditField.Value;
			app.signal_period = value;
			SwitchValueChanged(app);
		end

		% Button pushed function: DisconnectButton
		function DisconnectButtonPushed(app, event)
			warning off all
			snew = instrfind;
			clear snew
			warning on all
			app.connect_lamp.Color='Red';
			app.serpo=[];
		end
	end

	% Component initialization
	methods (Access = private)

		% Create UIFigure and components
		function createComponents(app)
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			app.OLT_Delay_Generator_Fig = uifigure('Visible', 'off');
			app.OLT_Delay_Generator_Fig.Name = 'Delay Generator';
			app.OLT_Delay_Generator_Fig.CloseRequestFcn = createCallbackFcn(app, @OLT_Delay_Generator_FigCloseRequest, true);
			app.OLT_Delay_Generator_Fig.KeyPressFcn = createCallbackFcn(app, @OLT_Delay_Generator_FigKeyPress, true);
			app.DevicenameEditFieldLabel = uilabel(app.OLT_Delay_Generator_Fig);
			app.DevicenameEditFieldLabel.Text = 'Device name';
			app.DevicenameEditField = uieditfield(app.OLT_Delay_Generator_Fig, 'text');
			app.DevicenameEditField.Value = 'DelGen';
			app.PortDropDownLabel = uilabel(app.OLT_Delay_Generator_Fig);
			app.PortDropDownLabel.Text = 'Port';
			app.PortDropDown = uidropdown(app.OLT_Delay_Generator_Fig);
			app.PortDropDown.Items = {'COM1'};
			app.PortDropDown.Value = 'COM1';
			app.RefreshButton = uibutton(app.OLT_Delay_Generator_Fig, 'push');
			app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
			app.RefreshButton.Text = 'Refresh';
			app.connect_lamp = uilamp(app.OLT_Delay_Generator_Fig);
			app.ConnectButton = uibutton(app.OLT_Delay_Generator_Fig, 'push');
			app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
			app.ConnectButton.Text = 'Connect';
			app.DisconnectButton = uibutton(app.OLT_Delay_Generator_Fig, 'push');
			app.DisconnectButton.ButtonPushedFcn = createCallbackFcn(app, @DisconnectButtonPushed, true);
			app.DisconnectButton.Text = 'Disconnect';
			app.Signal_waitLabel = uilabel(app.OLT_Delay_Generator_Fig);
			app.Signal_waitLabel.Text = 'Signal wait in µs';
			app.Signal_wait_EditField = uieditfield(app.OLT_Delay_Generator_Fig, 'numeric');
			app.Signal_wait_EditField.Limits = [2.7 1000000];
			app.Signal_wait_EditField.ValueDisplayFormat = '%.1f';
			app.Signal_wait_EditField.ValueChangedFcn = createCallbackFcn(app, @Signal_wait_EditFieldValueChanged2, true);
			app.Signal_wait_EditField.Value = 3;
			app.Signal_waitLabel_2 = uilabel(app.OLT_Delay_Generator_Fig);
			app.Signal_waitLabel_2.Text = 'Signal period in µs';
			app.Signal_Period_EditField = uieditfield(app.OLT_Delay_Generator_Fig, 'numeric');
			app.Signal_Period_EditField.Limits = [2.7 1000000];
			app.Signal_Period_EditField.ValueDisplayFormat = '%.1f';
			app.Signal_Period_EditField.ValueChangedFcn = createCallbackFcn(app, @Signal_Period_EditFieldValueChanged, true);
			app.Signal_Period_EditField.Value = 20;
			app.EdgereactDropDownLabel = uilabel(app.OLT_Delay_Generator_Fig);
			app.EdgereactDropDownLabel.Text = 'Edge react';
			app.EdgereactDropDown = uidropdown(app.OLT_Delay_Generator_Fig);
			app.EdgereactDropDown.Items = {'Rising', 'Falling'};
			app.EdgereactDropDown.ValueChangedFcn = createCallbackFcn(app, @EdgereactDropDownValueChanged, true);
			app.EdgereactDropDown.Value = 'Rising';
			app.Switch = uiswitch(app.OLT_Delay_Generator_Fig, 'slider');
			app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
			app.runninglamp = uilamp(app.OLT_Delay_Generator_Fig);
			app.RunningLabel = uilabel(app.OLT_Delay_Generator_Fig);
			app.RunningLabel.Text = 'Running';
			app.TextArea = uitextarea(app.OLT_Delay_Generator_Fig);
			app.TextArea.Editable = 'off';
			app.TextArea.Value = {'Blalbla'; 'Noch eine Zeile'; 'Und noch eine'};
			% Show the figure after all components are created
			app.OLT_Delay_Generator_Fig.Visible = 'on';
		end
	end

	% App creation and deletion
	methods (Access = public)

		% Construct app
		function app = PIVlab_Delay_Generator

			runningApp = getRunningApp(app);

			% Check for running singleton app
			if isempty(runningApp)

				% Create UIFigure and components
				createComponents(app)

				% Register the app with App Designer
				registerApp(app, app.OLT_Delay_Generator_Fig)

				% Execute the startup function
				runStartupFcn(app, @startupFcn)
			else

				% Focus the running singleton app
				figure(runningApp.OLT_Delay_Generator_Fig)

				app = runningApp;
			end

			if nargout == 0
				clear app
			end
		end

		% Code that executes before app deletion
		function delete(app)

			% Delete UIFigure when app is deleted
			delete(app.OLT_Delay_Generator_Fig)
		end
	end
end