classdef corr_demo < matlab.apps.AppBase

	% Properties that correspond to app components
	properties (Access = public)
		CorrelationdemobasedonPoelmavanderBaanUIFigure  matlab.ui.Figure
		GridLayout                matlab.ui.container.GridLayout
		LeftPanel                 matlab.ui.container.Panel
		Correlationdisplay        matlab.ui.control.DropDown
		CorrelationdisplayDropDownLabel  matlab.ui.control.Label
		multipass                 matlab.ui.control.CheckBox
		addgradient               matlab.ui.control.CheckBox
		addreflection             matlab.ui.control.CheckBox
		outofplane                matlab.ui.control.Slider
		OutofplanelossLabel       matlab.ui.control.Label
		noisecount                matlab.ui.control.Slider
		AmountnoiseLabel          matlab.ui.control.Label
		RefreshrateDropDown       matlab.ui.control.DropDown
		RefreshrateDropDownLabel  matlab.ui.control.Label
		intarea_size              matlab.ui.control.Slider
		part_sizeLabel_2          matlab.ui.control.Label
		part_size                 matlab.ui.control.Slider
		part_sizeLabel            matlab.ui.control.Label
		Pause                     matlab.ui.control.Button
		no_parts                  matlab.ui.control.Slider
		no_partLabel              matlab.ui.control.Label
		vert_displace             matlab.ui.control.Slider
		displ_yLabel              matlab.ui.control.Label
		hor_displace              matlab.ui.control.Slider
		displ_xLabel              matlab.ui.control.Label
		RightPanel                matlab.ui.container.Panel
		GridLayout2               matlab.ui.container.GridLayout
		GridLayout3               matlab.ui.container.GridLayout
		rms_error                 matlab.ui.control.Label
		bias_error                matlab.ui.control.Label
		axes5                     matlab.ui.control.UIAxes
		axes4                     matlab.ui.control.UIAxes
		axes3                     matlab.ui.control.UIAxes
		axes2                     matlab.ui.control.UIAxes
		axes1                     matlab.ui.control.UIAxes
	end

	% Properties that correspond to apps with auto-reflow
	properties (Access = private)
		onePanelWidth = 576;
	end

	properties (Access = private)
		refresh_timer % Timer to refresh display
		cntr=0; % iteration of the display
		imsize=100;
		I
		I1
		I2
		xp
		x0
		y0
		yp
		Ip
		iar
		ia1
		ia2
		Itemp
		Itemp2
		Irefl
		xc
		ac
		mac
		dist
		xr
		yr
		maxint
		img1handle
		img2handle
		img3handle
		plot1handle
		plot2handle
		crosshair_roi = images.roi.Crosshair;
		linex_roi = images.roi.Polyline;
		liney_roi = images.roi.Polyline;
		peak_x_history=zeros(100,1);
		peak_y_history=zeros(100,1);
	end

	methods (Access = private)
		function redraw_stuff(app)
			part_amount=round(app.no_parts.Value);
			psize=round(app.part_size.Value);
			iasize=round(app.intarea_size.Value/2)*2;
			vx=app.hor_displace.Value;
			vy=app.vert_displace.Value;
			if app.cntr==0 %only during initialization
				app.x0 = rand(part_amount,1).*app.imsize;
				app.y0 = rand(part_amount,1).*app.imsize;
				app.I=zeros(app.imsize,app.imsize);
				app.I1=app.I;
				app.I2=app.I;
				app.maxint = 256;
				app.xr = 1:app.imsize;
				app.yr = 1:app.imsize;
				[app.xr,app.yr] = meshgrid(app.xr,app.yr);

				app.Irefl = app.I;
				for k = 1:(app.imsize-1)
					app.Irefl(round(71-k./5),k) = 128;
					app.Irefl(round(72-k./5),k) = 128;
				end

				set(app.axes1,'DataAspectRatio',[1 1 1])
				set(app.axes2,'DataAspectRatio',[1 1 1])

				app.axes1.Colormap  = gray;
				app.axes2.Colormap  = gray;
				app.axes3.Colormap  = parula;

				app.img1handle=imagesc(zeros(app.imsize,app.imsize),'Parent',app.axes1);
				app.img2handle=imagesc(zeros(app.imsize,app.imsize),'Parent',app.axes2);
				if strcmp(app.Correlationdisplay.Value, '2D')
					reset(app.axes3)
					app.img3handle=imagesc(zeros(app.imsize,iasize),'Parent',app.axes3);
					set(app.axes3,'DataAspectRatio',[1 1 1])
					set(app.axes3,'Clipping','On')
					app.axes3.XLabel.String = '';
					app.axes3.YLabel.String = '';
					app.axes3.ZLabel.String = '';
				else
					app.img3handle=surf(zeros(app.imsize,iasize),'Parent',app.axes3);
					set(app.axes3,'DataAspectRatio',[1 1 0.04])
					set(app.axes3,'Clipping','Off')
					app.axes3.ZLim=[0 1];
					app.axes3.XLabel.String = 'x';
					app.axes3.YLabel.String = 'y';
					app.axes3.ZLabel.String = 'correlation';
				end
				app.plot1handle=plot(app.peak_x_history,'Parent',app.axes4);
				app.plot2handle=plot(app.peak_y_history,'Parent',app.axes5);
				app.axes1.XLim=[1 app.imsize];
				app.axes1.YLim=[1 app.imsize];
				app.axes2.XLim=[1 app.imsize];
				app.axes2.YLim=[1 app.imsize];

				app.axes3.XLim=[1 iasize*1];
				app.axes3.YLim=[1 iasize*1];

				app.axes3.ZLim=[0 1];

				app.crosshair_roi = images.roi.Crosshair(app.axes3);
				app.crosshair_roi.Parent = app.axes3;
				app.crosshair_roi.InteractionsAllowed = 'none';
				app.crosshair_roi.LineWidth=1;

				app.linex_roi = images.roi.Polyline(app.axes4);
				app.linex_roi.Parent = app.axes4;
				app.linex_roi.InteractionsAllowed = 'none';
				app.linex_roi.LineWidth=2;
				app.linex_roi.Color=[0 1 1];

				app.liney_roi = images.roi.Polyline(app.axes5);
				app.liney_roi.Parent = app.axes5;
				app.liney_roi.InteractionsAllowed = 'none';
				app.liney_roi.LineWidth=2;
				app.liney_roi.Color=[0 1 1];
			end

			try

				app.cntr=app.cntr+1;
				app.I = zeros(app.imsize,app.imsize);

				app.I1 = app.I2;


				if size(app.x0,1) < part_amount
					app.x0 = rand(part_amount,1).*app.imsize;
					app.y0 = rand(part_amount,1).*app.imsize;
				end

				for i = 1:part_amount
					app.xp = app.x0(i);
					app.yp = app.y0(i);
					app.dist = (app.xr-app.xp).^2 + (app.yr-app.yp).^2;
					app.Ip = 255.*exp(-app.dist/psize);
					app.I = app.I + app.Ip;
				end

				app.I(app.I>app.maxint) = app.maxint;

				if app.addgradient.Value==0
					app.x0 = app.x0 + vx;
					app.y0 = app.y0 + vy;
				else
					app.x0 = app.x0 + vx;
					app.x0 = app.x0 + vx.*(app.y0-app.imsize/2)./20;
					app.y0 = app.y0 + vy;
					app.y0 = app.y0 + vy.*(app.x0-app.imsize/2)./20;
				end

				nv = app.x0>app.imsize;

				for k = find(nv==1)
					app.x0(k) = app.x0(k)-app.imsize;
				end
				nv = app.x0<0;
				for k = find(nv==1)
					app.x0(k) = app.x0(k)+app.imsize;
				end

				nv = app.y0>app.imsize;

				for k = find(nv==1)
					app.y0(k) = app.y0(k)-app.imsize;
				end
				nv = app.y0<0;
				for k = find(nv==1)
					app.y0(k) = app.y0(k)+app.imsize;
				end
				perc_loss = rand(part_amount,1);
				nv = perc_loss < app.outofplane.Value;
				xnew = rand(part_amount,1).*app.imsize;
				ynew = rand(part_amount,1).*app.imsize;
				app.x0(nv) = xnew(nv);
				app.y0(nv) = ynew(nv);
				app.I2 = app.I;
				app.iar = -(iasize/2-1):(iasize/2);
				if app.addreflection.Value==1
					app.I1 = app.I1 + app.Irefl;
					app.I2 = app.I2 + app.Irefl;
				end
				noise_count = app.noisecount.Value*255;
				app.I1 = app.I1 + rand(app.imsize,app.imsize).*noise_count; % add some noise to the images
				app.I2 = app.I2 + rand(app.imsize,app.imsize).*noise_count;
				if app.multipass.Value==0
					app.ia1 = app.I1(app.imsize/2+app.iar,app.imsize/2+app.iar); % cut out first IA without shift
				else
					app.ia1 = app.I1(app.imsize/2+app.iar-round(vy),app.imsize/2+app.iar-round(vx)); % cut out first IA without shift
				end

				app.ia2 = app.I2(app.imsize/2+app.iar,app.imsize/2+app.iar); % cut out second IA

				app.Itemp = app.I1; % visualize int. area by adding a constant value
				app.Itemp(app.imsize/2+app.iar,app.imsize/2+app.iar) = app.Itemp(app.imsize/2+app.iar,app.imsize/2+app.iar) + 128;

				app.Itemp2 = app.I2; % visualize int. area
				app.Itemp2(app.imsize/2+app.iar,app.imsize/2+app.iar) = app.Itemp2(app.imsize/2+app.iar,app.imsize/2+app.iar) + 128;


				app.ia1 = app.ia1-mean2(app.ia1);
				app.ia2 = app.ia2-mean2(app.ia2);
				app.xc = fftshift(ifft2(fft2(app.ia2,iasize*2,iasize*2).*conj(fft2(app.ia1,iasize*2,iasize*2)))); % with zero padding
				app.ac = fftshift(ifft2(fft2(app.ia1,iasize*2,iasize*2).*conj(fft2(app.ia1,iasize*2,iasize*2))));
				app.mac = max(app.ac(:)); % get height of highest peak
				correlation_matrix = app.xc./app.mac;
				%cropping the correlation matrix so it has same size as int area again
				idx1=size(correlation_matrix,1)/4*1 ;
				idx2=size(correlation_matrix,1)/4*3;
				correlation_matrix=correlation_matrix(idx1:idx2,idx1:idx2);

				corval = max(app.xc(:))./app.mac;
				[peaky,peakx] = find(correlation_matrix==max(correlation_matrix(:)));
				%%{
				if isempty(peakx)
					peakx=2;
				end
				if isempty(peaky)
					peaky=2;
				end
				peaky=peaky(1);
				peakx=peakx(1);
				if peakx==1
					peakx=2;
				end
				if peaky==1
					peaky=2;
				end
				if peakx==size(correlation_matrix,1)
					peakx=2;
				end
				if peaky==size(correlation_matrix,1)
					peaky=2;
				end

				%%}
				%the following 8 lines are copyright (c) 1998, Uri Shavit, Roi Gurka, Alex Liberzon, Technion – Israel Institute of Technology
				%http://urapiv.wordpress.com
				f0 = log(correlation_matrix(peaky,peakx));
				f1 = log(correlation_matrix(peaky-1,peakx));
				f2 = log(correlation_matrix(peaky+1,peakx));
				subpeaky = peaky+ (f1-f2)/(2*f1-4*f0+2*f2);
				f0 = log(correlation_matrix(peaky,peakx));
				f1 = log(correlation_matrix(peaky,peakx-1));
				f2 = log(correlation_matrix(peaky,peakx+1));
				subpeakx = peakx+ (f1-f2)/(2*f1-4*f0+2*f2);

				if ~isreal(subpeakx)
					subpeakx = real(subpeakx);
				end
				if ~isreal(subpeaky)
					subpeaky = real(subpeaky);
				end

				if isnan(subpeakx)
					subpeakx = 1;
				end
				if isnan(subpeaky)
					subpeaky = 1;
				end
				if app.multipass.Value==1
					subpeakx=subpeakx+round(vx);
					subpeaky=subpeaky+round(vy);
				end

				if ~isempty(subpeakx)
					%%xaxis
					app.peak_x_history(end+1,1)=subpeakx-size(correlation_matrix,2)/2-1.5;
					app.peak_x_history(1,:)=[];

					app.plot1handle.XData=1 :numel(app.peak_x_history);
					app.plot1handle.YData=app.peak_x_history;
					app.axes4.XLim=[1 100];

					app.axes4.YLim=[mean(app.peak_x_history(:),'omitnan')-4*std(app.peak_x_history(:),'omitnan')-0.1 mean(app.peak_x_history(:),'omitnan')+4*std(app.peak_x_history(:),'omitnan')+0.1];
					app.linex_roi.Position=[1 vx; 100 vx];

					%% y axis
					app.peak_y_history(end+1,1)=subpeaky-size(correlation_matrix,2)/2-1.5;
					app.peak_y_history(1,:)=[];

					app.plot2handle.XData=1 :numel(app.peak_y_history);
					app.plot2handle.YData=app.peak_y_history;
					app.axes5.XLim=[1 100];

					app.axes5.YLim=[mean(app.peak_y_history(:),'omitnan')-4*std(app.peak_y_history(:),'omitnan')-0.1 mean(app.peak_y_history(:),'omitnan')+4*std(app.peak_y_history(:),'omitnan')+0.1];
					app.liney_roi.Position=[1 vy; 100 vy];
				end
				if ~isnan(corval)
					app.axes3.Title.String= ['max cor.: ' sprintf('%0.2f',corval) '; peak x: '  sprintf('%0.2f',subpeakx-size(correlation_matrix,2)/2-1.5) '; peak y: '  sprintf('%0.2f',subpeaky-size(correlation_matrix,2)/2-1.5)];
				end
				app.img1handle.CData = app.Itemp;
				app.img2handle.CData = app.Itemp2;
				if ~isempty(correlation_matrix)
					if strcmp(app.Correlationdisplay.Value,'2D')
						app.img3handle.CData = correlation_matrix;
					else
						app.img3handle.ZData = correlation_matrix;
						set(app.axes3,'DataAspectRatio',[1 1 1/iasize])
					end
				end
				if ~isempty(peakx)
					if app.multipass.Value==1
						app.crosshair_roi.Position = [subpeakx-round(vx),subpeaky-round(vy)];
					else
						app.crosshair_roi.Position = [subpeakx,subpeaky];
					end
				end
				bias_error_x = mean(app.peak_x_history - vx,'omitnan');
				rms_error_x = std(app.peak_x_history,'omitnan');
				bias_error_y = mean(app.peak_y_history - vy,'omitnan');
				rms_error_y = std(app.peak_y_history,'omitnan');
				bias_error_tot = (bias_error_x.^2+bias_error_y.^2).^0.5;
				rms_error_tot = (rms_error_x.^2+rms_error_y.^2).^0.5;
				app.bias_error.Text=['Bias Error: ' sprintf('%0.2f',bias_error_tot) ' px'];
				app.rms_error.Text=['RMS Error: ' sprintf('%0.2f',rms_error_tot) ' px'];
			catch ME
				disp('error in lines')
				ME.stack.line
				ME.message
			end
			drawnow limitrate

		end

	end

	% Callbacks that handle component events
	methods (Access = private)

		% Code that executes after component creation
		function startupFcn(app)
			warning off
			delete(timerfind);
			warning on
			app.cntr=0;
			app.refresh_timer = timer('Period',1/10,'Name','MyTimer','ExecutionMode','fixedRate');
			app.refresh_timer.TimerFcn = @(~,~)app.redraw_stuff;
			drawnow
			pause(1)
			start(app.refresh_timer)
		end

		% Button pushed function: Pause
		function Pause_sim(app, event)
			if strcmp (app.refresh_timer.Running,'on')
				stop(app.refresh_timer)
				app.Pause.Text='Restart';
			elseif strcmp (app.refresh_timer.Running,'off')
				app.Pause.Text='Pause';
				app.cntr=0;
				stop(app.refresh_timer)
				pause(0.1)
				start(app.refresh_timer)
			end
		end

		% Close request function: 
		% CorrelationdemobasedonPoelmavanderBaanUIFigure
		function CorrelationdemobasedonPoelmavanderBaanUIFigureCloseRequest(app, event)
			try
				stop(app.refresh_timer)
			catch
			end
			warning off
			delete(timerfind);
			warning on
			delete(app)
		end

		% Value changed function: Correlationdisplay, RefreshrateDropDown, 
		% ...and 1 other component
		function ValueChanged(app, event)
			app.cntr=0;
			stop(app.refresh_timer)
			switch app.RefreshrateDropDown.Value
				case '5 Hz'
					refresh=1/5;
				case '10 Hz'
					refresh=1/10;
				case '20 Hz'
					refresh=1/20;
				case '40 Hz'
					refresh=1/40;
			end
			app.refresh_timer.Period = refresh ;
			start(app.refresh_timer)
		end

		% Changes arrangement of the app based on UIFigure width
		function updateAppLayout(app, event)
			currentFigureWidth = app.CorrelationdemobasedonPoelmavanderBaanUIFigure.Position(3);
			if(currentFigureWidth <= app.onePanelWidth)
			    % Change to a 2x1 grid
			    app.GridLayout.RowHeight = {603, 603};
			    app.GridLayout.ColumnWidth = {'1x'};
			    app.RightPanel.Layout.Row = 2;
			    app.RightPanel.Layout.Column = 1;
			else
			    % Change to a 1x2 grid
			    app.GridLayout.RowHeight = {'1x'};
			    app.GridLayout.ColumnWidth = {299, '1x'};
			    app.RightPanel.Layout.Row = 1;
			    app.RightPanel.Layout.Column = 2;
			end
		end
	end

	% Component initialization
	methods (Access = private)

		% Create UIFigure and components
		function createComponents(app)
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			app.CorrelationdemobasedonPoelmavanderBaanUIFigure = uifigure('Visible', 'off');
			app.CorrelationdemobasedonPoelmavanderBaanUIFigure.Name = 'Correlation demo, based on Poelma & van der Baan ';
			app.CorrelationdemobasedonPoelmavanderBaanUIFigure.CloseRequestFcn = createCallbackFcn(app, @CorrelationdemobasedonPoelmavanderBaanUIFigureCloseRequest, true);
			app.CorrelationdemobasedonPoelmavanderBaanUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
			app.GridLayout = uigridlayout(app.CorrelationdemobasedonPoelmavanderBaanUIFigure);
			app.LeftPanel = uipanel(app.GridLayout);
			app.displ_xLabel = uilabel(app.LeftPanel);
			app.displ_xLabel.Text = 'x displacement';
			app.hor_displace = uislider(app.LeftPanel);
			app.hor_displace.Limits = [-20 20];
			app.hor_displace.Value = 4;
			app.displ_yLabel = uilabel(app.LeftPanel);
			app.displ_yLabel.Text = 'y displacement';
			app.vert_displace = uislider(app.LeftPanel);
			app.vert_displace.Limits = [-20 20];
			app.no_partLabel = uilabel(app.LeftPanel);
			app.no_partLabel.Text = {'Number of '; 'particles'};
			app.no_parts = uislider(app.LeftPanel);
			app.no_parts.Limits = [1 400];
			app.no_parts.Value = 128;
			app.Pause = uibutton(app.LeftPanel, 'push');
			app.Pause.ButtonPushedFcn = createCallbackFcn(app, @Pause_sim, true);
			app.Pause.Text = 'Pause';
			app.part_sizeLabel = uilabel(app.LeftPanel);
			app.part_sizeLabel.Text = 'Particle size';
			app.part_size = uislider(app.LeftPanel);
			app.part_size.Limits = [1 10];
			app.part_size.Value = 1;
			app.part_sizeLabel_2 = uilabel(app.LeftPanel);
			app.part_sizeLabel_2.Text = {'Size of the '; 'interrogation area'};
			app.intarea_size = uislider(app.LeftPanel);
			app.intarea_size.Limits = [8 96];
			app.intarea_size.MajorTicks = [8 48 88 128];
			app.intarea_size.MajorTickLabels = {'8', '48', '88', '128'};
			app.intarea_size.ValueChangedFcn = createCallbackFcn(app, @ValueChanged, true);
			app.intarea_size.MinorTicks = [8 16 24 32 40 48 56 64 72 80 88 96 104 112 120 128];
			app.intarea_size.Value = 32;
			app.RefreshrateDropDownLabel = uilabel(app.LeftPanel);
			app.RefreshrateDropDownLabel.Text = 'Refresh rate';
			app.RefreshrateDropDown = uidropdown(app.LeftPanel);
			app.RefreshrateDropDown.Items = {'5 Hz', '10 Hz', '20 Hz', '40 Hz'};
			app.RefreshrateDropDown.ValueChangedFcn = createCallbackFcn(app, @ValueChanged, true);
			app.RefreshrateDropDown.Value = '10 Hz';
			app.AmountnoiseLabel = uilabel(app.LeftPanel);
			app.AmountnoiseLabel.Text = 'Amount noise';
			app.noisecount = uislider(app.LeftPanel);
			app.noisecount.Limits = [0 1];
			app.OutofplanelossLabel = uilabel(app.LeftPanel);
			app.OutofplanelossLabel.Text = 'Out of plane loss';
			app.outofplane = uislider(app.LeftPanel);
			app.outofplane.Limits = [0 1];
			app.addreflection = uicheckbox(app.LeftPanel);
			app.addreflection.Text = 'Add reflection';
			app.addgradient = uicheckbox(app.LeftPanel);
			app.addgradient.Text = 'Add gradient';
			app.multipass = uicheckbox(app.LeftPanel);
			app.multipass.Text = 'Simulate multipass';
			app.CorrelationdisplayDropDownLabel = uilabel(app.LeftPanel);
			app.CorrelationdisplayDropDownLabel.Text = 'Correlation display';
			app.Correlationdisplay = uidropdown(app.LeftPanel);
			app.Correlationdisplay.Items = {'2D', '3D'};
			app.Correlationdisplay.ValueChangedFcn = createCallbackFcn(app, @ValueChanged, true);
			app.Correlationdisplay.Value = '2D';
			app.RightPanel = uipanel(app.GridLayout);
			app.GridLayout2 = uigridlayout(app.RightPanel);
			app.axes1 = uiaxes(app.GridLayout2);
			title(app.axes1, 'interrogation area A')
			app.axes1.XLim = [0 1];
			app.axes1.XTick = [];
			app.axes1.XTickLabel = {};
			app.axes1.YTick = [];
			app.axes1.YTickLabel = {};
			app.axes1.ZTick = [];
			app.axes1.ZTickLabel = '';
			colormap(app.axes1, 'parula')
			app.axes2 = uiaxes(app.GridLayout2);
			title(app.axes2, 'interrogation area B')
			app.axes2.XTick = [];
			app.axes2.XTickLabel = {};
			app.axes2.YTick = [];
			app.axes2.YTickLabel = {};
			app.axes2.ZTick = [];
			app.axes2.ZTickLabel = '';
			colormap(app.axes2, 'parula')
			app.axes3 = uiaxes(app.GridLayout2);
			title(app.axes3, 'Correlation matrix')
			app.axes3.XTick = [];
			app.axes3.XTickLabel = {};
			app.axes3.YTick = [];
			app.axes3.YTickLabel = {};
			app.axes3.ZTick = [];
			app.axes3.ZTickLabel = '';
			colormap(app.axes3, 'parula')
			app.axes4 = uiaxes(app.GridLayout2);
			title(app.axes4, 'Measured x displacement')
			xlabel(app.axes4, 'time')
			ylabel(app.axes4, 'peak x position in px')
			app.axes4.XLim = [0 1];
			app.axes4.XTick = [];
			app.axes4.XTickLabel = '';
			app.axes4.ZTick = [];
			app.axes4.ZTickLabel = '';
			colormap(app.axes4, 'parula')
			app.axes5 = uiaxes(app.GridLayout2);
			title(app.axes5, 'Measured y displacement')
			xlabel(app.axes5, 'time')
			ylabel(app.axes5, 'peak y position in px')
			app.axes5.XLim = [0 1];
			app.axes5.XTick = [];
			app.axes5.XTickLabel = '';
			app.axes5.ZTick = [];
			app.axes5.ZTickLabel = '';
			colormap(app.axes5, 'parula')
			app.GridLayout3 = uigridlayout(app.GridLayout2);
			app.bias_error = uilabel(app.GridLayout3);
			app.bias_error.Text = 'Bias Error: 0.00 px';
			app.rms_error = uilabel(app.GridLayout3);
			app.rms_error.Text = 'RMS Error: 0.00 px';
			% Show the figure after all components are created
			app.CorrelationdemobasedonPoelmavanderBaanUIFigure.Visible = 'on';
		end
	end

	% App creation and deletion
	methods (Access = public)

		% Construct app
		function app = corr_demo

			runningApp = getRunningApp(app);

			% Check for running singleton app
			if isempty(runningApp)

				% Create UIFigure and components
				createComponents(app)

				% Register the app with App Designer
				registerApp(app, app.CorrelationdemobasedonPoelmavanderBaanUIFigure)

				% Execute the startup function
				runStartupFcn(app, @startupFcn)
			else

				% Focus the running singleton app
				figure(runningApp.CorrelationdemobasedonPoelmavanderBaanUIFigure)

				app = runningApp;
			end

			if nargout == 0
				clear app
			end
		end

		% Code that executes before app deletion
		function delete(app)

			% Delete UIFigure when app is deleted
			delete(app.CorrelationdemobasedonPoelmavanderBaanUIFigure)
		end
	end
end